classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        UITable2                      matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        loaddataButton                matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        TrainingProgressLabel         matlab.ui.control.Label
        trainingButton                matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        ConfusionChartPanel           matlab.ui.container.Panel
        accuracyalarmThreshold80LampLabel  matlab.ui.control.Label
        accuracyalarmThreshold80Lamp  matlab.ui.control.Lamp
        accuracyLabel                 matlab.ui.control.Label
        accuracyEditField             matlab.ui.control.NumericEditField
        classifyButton                matlab.ui.control.Button
        accuracyGauge                 matlab.ui.control.Gauge
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access=public, SetObservable, AbortSet)
        model
        info
        XTrain
        YTrain
        XTest
        YTest
        YPred
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: UITable, UITable2, loaddataButton
        function loadButtonPushed(app, event)
            addpath('TestData', 'TrainingData')
            testDataDir =dir('TestData');
            testFileNames = {testDataDir.name};
            testFileNames = testFileNames(3:end);
            trainingDataDir = dir('TrainingData');
            trainingFileNames = {trainingDataDir.name};
            trainingFileNames = trainingFileNames(3:end);
            [app.XTrain, app.YTrain, app.XTest, app.YTest] = load_dataset(trainingFileNames, testFileNames);
            app.UITable.Data= trainingFileNames(:);
            app.UITable2.Data = testFileNames(:);
        end

        % Callback function: UIAxes, UIAxes2, trainingButton
        function trainButtonPushed(app, event)
            [app.model,app.info] = trainSillyWalkClassifier(app.XTrain, app.YTrain);
            x=1:1:length(app.info.TrainingAccuracy);
            y1=app.info.TrainingAccuracy;
            plot(app.UIAxes,x,y1)
            y2=app.info.TrainingLoss;
            plot(app.UIAxes2,x,y2)         
        end

        % Callback function: ConfusionChartPanel, classifyButton
        function classifyButtonPushed(app, event)
            app.YPred = classifyWalk(app.model, app.XTest);
            accuracy = sum(app.YPred == app.YTest)/numel(app.YTest)*100;
            app.accuracyEditField.Value=accuracy;
            app.accuracyGauge.Value=accuracy;
            if accuracy>80
                app.accuracyalarmThreshold80Lamp.Color='y';
            elseif accuracy>90
                app.accuracyalarmThreshold80Lamp.Color='g';
            else
                app.accuracyalarmThreshold80Lamp.Color='r';
            end
            cm=confusionmat(app.YTest, app.YPred);
            xvalues={'N','S'};
            yvalues={'N','S'};
            heatmap(app.ConfusionChartPanel,xvalues,yvalues,cm);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {618, 618, 618};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {618, 618};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x', 219};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.loaddataButton = uibutton(app.LeftPanel, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Training data'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @loadButtonPushed, true);
            app.UITable2 = uitable(app.LeftPanel);
            app.UITable2.ColumnName = {'Test data'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @loadButtonPushed, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'Iteration')
            ylabel(app.UIAxes, 'Accuracy(%)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @trainButtonPushed, true);
            app.UIAxes2 = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes2, 'Iteration')
            ylabel(app.UIAxes2, 'Loss')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @trainButtonPushed, true);
            app.trainingButton = uibutton(app.CenterPanel, 'push');
            app.trainingButton.ButtonPushedFcn = createCallbackFcn(app, @trainButtonPushed, true);
            app.trainingButton.Text = 'training';
            app.TrainingProgressLabel = uilabel(app.CenterPanel);
            app.TrainingProgressLabel.Text = 'Training Progress';
            app.RightPanel = uipanel(app.GridLayout);
            app.accuracyGauge = uigauge(app.RightPanel, 'circular');
            app.classifyButton = uibutton(app.RightPanel, 'push');
            app.classifyButton.ButtonPushedFcn = createCallbackFcn(app, @classifyButtonPushed, true);
            app.classifyButton.Text = 'classify';
            app.accuracyEditField = uieditfield(app.RightPanel, 'numeric');
            app.accuracyEditField.Editable = 'off';
            app.accuracyLabel = uilabel(app.RightPanel);
            app.accuracyLabel.Text = 'accuracy:';
            app.accuracyalarmThreshold80Lamp = uilamp(app.RightPanel);
            app.accuracyalarmThreshold80LampLabel = uilabel(app.RightPanel);
            app.accuracyalarmThreshold80LampLabel.Text = {'accuracy alarm'; '(Threshold = 80%)'};
            app.ConfusionChartPanel = uipanel(app.RightPanel);
            app.ConfusionChartPanel.Title = 'Confusion Chart';
            app.ConfusionChartPanel.SizeChangedFcn = createCallbackFcn(app, @classifyButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end